classdef Audio_Signals_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportMergeAudiotoFileButton  matlab.ui.control.Button
        AudioOperationPanel           matlab.ui.container.Panel
        GridLayout2_2                 matlab.ui.container.GridLayout
        NormalizeCheckBox             matlab.ui.control.CheckBox
        Trims                         matlab.ui.control.CheckBox
        Alen                          matlab.ui.control.Label
        AudioLengthsLabel             matlab.ui.control.Label
        EndSpinner                    matlab.ui.control.Spinner
        EndSpinnerLabel               matlab.ui.control.Label
        StartSpinner                  matlab.ui.control.Spinner
        StartSpinnerLabel             matlab.ui.control.Label
        TrimLabel                     matlab.ui.control.Label
        PleaseselectfilefromlistboxLabel  matlab.ui.control.Label
        SaveasButton                  matlab.ui.control.Button
        ThismightreduceOriginalfileaudioqualityLabel  matlab.ui.control.Label
        AmplitudeSpinner              matlab.ui.control.Spinner
        AmplitudeSpinnerLabel         matlab.ui.control.Label
        ThismightChangeOriginalFileSampleRateLabel  matlab.ui.control.Label
        SpeedSpinner                  matlab.ui.control.Spinner
        SpeedSpinnerLabel             matlab.ui.control.Label
        NOTEThiswilloverwriteselectedfileLabel  matlab.ui.control.Label
        InvertCheckBox                matlab.ui.control.CheckBox
        ReverseCheckBox               matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        AudioFileSelectionPanel       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        MergeselectedfilesButton      matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        ListBox                       matlab.ui.control.ListBox
        SoundSettingPanel             matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Slider                        matlab.ui.control.Slider
        StopButton_2                  matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.StateButton
        PlayButton                    matlab.ui.control.Button
        al                            matlab.ui.control.Label
        tfile                         matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            app.ListBox.Items(index) = [];
            app.ListBox.ItemsData(index) = [];
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            if (isempty(app.ListBox.Items) ~= true)
                ListBoxValueChanged(app, event);
            end 
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            clear y;
            [filename,filepath] = uigetfile('*.wav');  %open a mat file
            filepath = strcat(filepath,filename);
            if (isempty(filepath) ~= true)
                app.tfile.Text = filepath;
                [y, fs] = audioread(filepath);
                t = (0:length(y)) / fs;
                t(end) = [];
                app.al.Text = strcat(num2str(t(end))," Second");
                plot(app.UIAxes,t, y);
                h=zoom(app.UIAxes);
                h.Motion = 'horizontal';
                h.Enable = 'on';
            
                itemList = app.ListBox.Items;
                itemData = app.ListBox.ItemsData;
                itemList{end+1} = filename;
                itemData{end+1} = filepath;
                app.ListBox.Items = itemList;
                app.ListBox.ItemsData = itemData;
            end
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            value = app.ListBox.ItemsData{index};
            app.tfile.Text = value;
            [y, fs] = audioread(value);
            t = (0:length(y)) / fs;
            t(end) = [];
            plot(app.UIAxes,t, y);
            
            k = app.Slider.Value;
            global player
            player = audioplayer(y*k, fs);
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                [y, fs] = audioread(filePath{1});
                global maxAMP;
                maxAMP = max(abs(y));
                audioLength = length(y)/fs;
                set(app.Alen,'Text',string(audioLength))
                set(app.StartSpinner,'Limits',[0,audioLength])
                set(app.EndSpinner,'Limits',[0,audioLength])
                set(app.EndSpinner,'Value',audioLength)
                set(app.AudioOperationPanel,'Enable','on')
                set(app.ReverseCheckBox,'Value',false)
                set(app.InvertCheckBox,'Value',false)
                set(app.Trims,'Value',false)
                set(app.SpeedSpinner,'Value',1)
                set(app.AmplitudeSpinner,'Value',1)
                set(app.PleaseselectfilefromlistboxLabel,'Text',filePath{1})
                set(app.StartSpinner,'Enable',false)
                set(app.EndSpinner,'Enable',false)
                set(app.NormalizeCheckBox,'Value',false)
            else
                set(app.AudioOperationPanel,'Enable','off')
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global player
            play(player);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            global player
            stop(player);
        end

        % Value changed function: PauseResumeButton
        function PauseResumeButtonValueChanged(app, event)
            value = app.PauseResumeButton.Value;
            global player
            if (value == 1) 
                pause(player);
            else
                resume(player);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global player
            [~,index] = ismember(app.ListBox.Value, app.ListBox.ItemsData);
            app.tfile.Text = string(isplaying(player));
            if (index ~= 0.0 & isplaying(player) ~= false)
                value = app.ListBox.ItemsData{index};
                pause(player);
                NewStart = get(player, 'CurrentSample')+1;
                stop(player);
                k = app.Slider.Value;
                [y, fs] = audioread(value);
                player = audioplayer(y*k, fs);
                play(player, NewStart);
            end
        end

        % Button pushed function: MergeselectedfilesButton
        function MergeselectedfilesButtonPushed(app, event)
            
            filePath = app.ListBox.Value;
            global player
            global mergeAudio
            global mergeFs
            if (length(filePath) <= 0)
                return
            end
            if (length(filePath) == 1)
                [y, fs] = audioread(filePath{1});
                t = (0:length(y)) / fs;
                t(end) = [];
                plot(app.UIAxes2,t, y); 
                %plot(app.UIAxes3,t,y);
                x = y;
                df = fs/length(x);
                f = -fs/2:df:fs/2-df;
                fftA = fftshift(fft(x))/length(fft(x));
                
                plot(app.UIAxes3, f, abs(fftA));
                %title ('FFT Spectrum');
                k = app.Slider.Value;
                player = audioplayer(y*k, fs);
                mergeAudio = y;
                mergeFs = fs;
                set(app.ExportMergeAudiotoFileButton,'Enable','on');
               return
            end
            
            for i = 2:length(filePath)
                if (i == 2)
                    [y1, fs1] = audioread(filePath{1});
                    
                    [y2, fs2] = audioread(filePath{2});
                    
                    lenY = size(y1, 1);
                    lenZ = size(y2, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y1, 2));
                    S(1:lenY, :) = y1;
                    S(1:lenZ, :) = S(1:lenZ, :) + y2;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y1) >= length(y2))
                        t = (1:length(y1)) / fs1;
                        fs = fs1;
                    else
                        t = (1:length(y2)) / fs2;
                        fs = fs2;
                    end
                else
                    [y, fs_] = audioread(filePath{i});
                    y_dum = S;
                    fs_dum = fs;
                    
                    lenY = size(y_dum, 1);
                    lenZ = size(y, 1);
                    len = max(lenY, lenZ);
                    S = zeros(len, size(y_dum, 2));
                    S(1:lenY, :) = y_dum;
                    S(1:lenZ, :) = S(1:lenZ, :) + y;
                    maxValue = max(abs(S(:)));
                    S = S / maxValue;
                    if (length(y_dum) >= length(y))
                        t = (1:length(y_dum)) / fs;
                        fs = fs_dum;
                    else
                        t = (1:length(y)) / fs_;
                        fs = fs_;
                    end
                end
            end
            plot(app.UIAxes2,t, S);
            x = S;
            Fs = fs;
            df = Fs/length(S);
            f = -Fs/2:df:Fs/2-df;
            fftA = fftshift(fft(x))/length(fft(x));
            
            plot(app.UIAxes3, f, abs(fftA));
            k = app.Slider.Value;
            player = audioplayer(S*k, Fs);
            mergeAudio = S;
            mergeFs = Fs;
            set(app.ExportMergeAudiotoFileButton,'Enable','on');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.AudioOperationPanel,'Enable','off')
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = flipud(y);
                    audiowrite(filePath{1},y2,fs);
                end
                if (app.InvertCheckBox.Value)
                    [y, fs] = audioread(filePath{1});
                    y2 = y * -1;
                    audiowrite(filePath{1},y2,fs);
                end
                if (app.Trims.Value)
                    [y, fs] = audioread(filePath{1});
                    samples=[fix(app.StartSpinner.Value * fs)+1,round(app.EndSpinner.Value * fs)];
                    [y1,fs2] = audioread(filePath{1},samples);
                    audiowrite(filePath{1},y1,fs2);
                end
                [y, fs] = audioread(filePath{1});
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filePath{1},y2,fix(app.SpeedSpinner.Value * fs));
                end
                set(app.ListBox, 'Value', {});
            end           
            
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            filter = {'*.wav';'*.*'};
            [file, path] = uiputfile(filter);
            filetarget = strcat(path,file);
            
            filePath = app.ListBox.Value;
            if (length(filePath) == 1)
                set(app.AudioOperationPanel,'Enable','off')
                [y, fs] = audioread(filePath{1});
                audiowrite(filetarget,y,fs);
                if (app.ReverseCheckBox.Value)
                    [y, fs] = audioread(filetarget);
                    y2 = flipud(y);
                    audiowrite(filetarget,y2,fs);
                end
                if (app.InvertCheckBox.Value)
                    [y, fs] = audioread(filetarget);
                    y2 = y * -1;
                    audiowrite(filetarget,y2,fs);
                    
                end
                if (app.Trims.Value)
                    [y, fs] = audioread(filetarget);
                    samples=[fix(app.StartSpinner.Value * fs)+1,round(app.EndSpinner.Value * fs)];
                    [y1,fs2] = audioread(filetarget,samples);
                    audiowrite(filetarget,y1,fs2);
                end
                [y, fs] = audioread(filetarget);
                newSampleRate = app.SpeedSpinner.Value * fs;
                if (newSampleRate >= 80 && newSampleRate <= 1000000) 
                    y2 = y * app.AmplitudeSpinner.Value;
                    audiowrite(filetarget,y2,fix(app.SpeedSpinner.Value * fs));
                end
                set(app.ListBox, 'Value', {});
                itemList = app.ListBox.Items;
                itemData = app.ListBox.ItemsData;
                itemList{end+1} = file;
                itemData{end+1} = filetarget;
                app.ListBox.Items = itemList;
                app.ListBox.ItemsData = itemData;
            end
        end

        % Value changed function: Trims
        function TrimsValueChanged(app, event)
            value = app.Trims.Value;
            if (value)
                set(app.StartSpinner,'Enable',true)
                set(app.EndSpinner,'Enable',true)
            else
                set(app.StartSpinner,'Enable',false)
                set(app.EndSpinner,'Enable',false)
            end
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            value = app.NormalizeCheckBox.Value;
            if (value)
                global maxAMP;
                newAMP = 1/max(maxAMP);
                set(app.AmplitudeSpinner,'Value',newAMP);
            else
                
            end
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            value = app.AmplitudeSpinner.Value;
            set(app.NormalizeCheckBox,'Value',false);
        end

        % Button pushed function: ExportMergeAudiotoFileButton
        function ExportMergeAudiotoFileButtonPushed(app, event)
            global mergeAudio
            global mergeFs
            filter = {'*.wav';'*.*'};
            [file, path] = uiputfile(filter);
            filetarget = strcat(path,file);
            audiowrite(filetarget,mergeAudio,mergeFs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Merge')
            xlabel(app.UIAxes2, 'Seconds')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Frequency Spectrum')
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Magnitude ')
            zlabel(app.UIAxes3, 'Z')
            app.tfile = uilabel(app.UIFigure);
            app.tfile.Text = 'NO SELECTED FILE';
            app.al = uilabel(app.UIFigure);
            app.al.Text = {''; ''};
            app.SoundSettingPanel = uipanel(app.UIFigure);
            app.SoundSettingPanel.Title = 'Sound Setting';
            app.GridLayout = uigridlayout(app.SoundSettingPanel);
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseResumeButton = uibutton(app.GridLayout, 'state');
            app.PauseResumeButton.ValueChangedFcn = createCallbackFcn(app, @PauseResumeButtonValueChanged, true);
            app.PauseResumeButton.Text = 'Pause / Resume';
            app.StopButton_2 = uibutton(app.GridLayout, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = {'Stop'; ''};
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.5 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.5;
            app.AudioFileSelectionPanel = uipanel(app.UIFigure);
            app.AudioFileSelectionPanel.Title = 'Audio File Selection';
            app.GridLayout2 = uigridlayout(app.AudioFileSelectionPanel);
            app.ListBox = uilistbox(app.GridLayout2);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.GridLayout2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MergeselectedfilesButton = uibutton(app.GridLayout2, 'push');
            app.MergeselectedfilesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeselectedfilesButtonPushed, true);
            app.MergeselectedfilesButton.Text = 'Merge selected files';
            app.AudioOperationPanel = uipanel(app.UIFigure);
            app.AudioOperationPanel.Enable = 'off';
            app.AudioOperationPanel.Title = 'Audio Operation';
            app.GridLayout2_2 = uigridlayout(app.AudioOperationPanel);
            app.SaveButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ReverseCheckBox = uicheckbox(app.GridLayout2_2);
            app.ReverseCheckBox.Text = 'Reverse';
            app.InvertCheckBox = uicheckbox(app.GridLayout2_2);
            app.InvertCheckBox.Text = 'Invert';
            app.NOTEThiswilloverwriteselectedfileLabel = uilabel(app.GridLayout2_2);
            app.NOTEThiswilloverwriteselectedfileLabel.Text = 'NOTE: This will over write selected file';
            app.SpeedSpinnerLabel = uilabel(app.GridLayout2_2);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.GridLayout2_2);
            app.SpeedSpinner.Step = 0.05;
            app.SpeedSpinner.Limits = [0.01 6];
            app.SpeedSpinner.Value = 1;
            app.ThismightChangeOriginalFileSampleRateLabel = uilabel(app.GridLayout2_2);
            app.ThismightChangeOriginalFileSampleRateLabel.Text = 'This might Change Original File Sample Rate';
            app.AmplitudeSpinnerLabel = uilabel(app.GridLayout2_2);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.GridLayout2_2);
            app.AmplitudeSpinner.Step = 0.05;
            app.AmplitudeSpinner.Limits = [0 10];
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.AmplitudeSpinner.Value = 1;
            app.ThismightreduceOriginalfileaudioqualityLabel = uilabel(app.GridLayout2_2);
            app.ThismightreduceOriginalfileaudioqualityLabel.Text = 'This might reduce Original file audio quality';
            app.SaveasButton = uibutton(app.GridLayout2_2, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as';
            app.PleaseselectfilefromlistboxLabel = uilabel(app.GridLayout2_2);
            app.PleaseselectfilefromlistboxLabel.Text = 'Please select file from list box';
            app.TrimLabel = uilabel(app.GridLayout2_2);
            app.TrimLabel.Text = 'Trim';
            app.StartSpinnerLabel = uilabel(app.GridLayout2_2);
            app.StartSpinnerLabel.Text = 'Start';
            app.StartSpinner = uispinner(app.GridLayout2_2);
            app.StartSpinner.Enable = 'off';
            app.EndSpinnerLabel = uilabel(app.GridLayout2_2);
            app.EndSpinnerLabel.Text = 'End';
            app.EndSpinner = uispinner(app.GridLayout2_2);
            app.EndSpinner.Enable = 'off';
            app.AudioLengthsLabel = uilabel(app.GridLayout2_2);
            app.AudioLengthsLabel.Text = 'AudioLength (s)';
            app.Alen = uilabel(app.GridLayout2_2);
            app.Alen.Text = '0';
            app.Trims = uicheckbox(app.GridLayout2_2);
            app.Trims.ValueChangedFcn = createCallbackFcn(app, @TrimsValueChanged, true);
            app.Trims.Text = '';
            app.NormalizeCheckBox = uicheckbox(app.GridLayout2_2);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.ExportMergeAudiotoFileButton = uibutton(app.UIFigure, 'push');
            app.ExportMergeAudiotoFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMergeAudiotoFileButtonPushed, true);
            app.ExportMergeAudiotoFileButton.Enable = 'off';
            app.ExportMergeAudiotoFileButton.Text = 'Export Merge Audio to File';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Signals_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end